classdef get_data_Test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        StartButton              matlab.ui.control.Button
        StopButton               matlab.ui.control.Button
        IMUArmEditFieldLabel     matlab.ui.control.Label
        IMUArmEditField          matlab.ui.control.EditField
        COMARDEditFieldLabel     matlab.ui.control.Label
        COMARDEditField          matlab.ui.control.EditField
        ConnectSensorsButton     matlab.ui.control.Button
        DisconnectSensorsButton  matlab.ui.control.Button
        Label                    matlab.ui.control.Label
        StatusLampLabel          matlab.ui.control.Label
        StatusLamp               matlab.ui.control.Lamp
        GestureKnobLabel         matlab.ui.control.Label
        GestureKnob              matlab.ui.control.DiscreteKnob
        InitialsEditFieldLabel   matlab.ui.control.Label
        InitialsEditField        matlab.ui.control.EditField
        IMUBackEditFieldLabel    matlab.ui.control.Label
        IMUBackEditField         matlab.ui.control.EditField
        LilyDropDownLabel        matlab.ui.control.Label
        LilyDropDown             matlab.ui.control.DropDown
        COMDropDownLabel         matlab.ui.control.Label
        COMDropDown              matlab.ui.control.DropDown
        LilysLabel               matlab.ui.control.Label
        LilysSpinner             matlab.ui.control.Spinner
        UITable                  matlab.ui.control.Table
        TestModeSwitchLabel      matlab.ui.control.Label
        TestModeSwitch           matlab.ui.control.Switch
    end


    properties (Access = private)
        lpSensorArm         % IMU Arm sensor
        lpSensorBack        % IMU Back sensor
        arduino             % Description
        stopButtonPressed   % Description
    end

    methods (Access = private)
    
        function enableButtons(app)
            app.StartButton.Enable = "on";
            app.StopButton.Enable = "on";
        end
        
        function disableButtons(app)
            app.StartButton.Enable = "off";
            app.StopButton.Enable = "off";
        end
        
        function enableConnections(app)
            app.IMUArmEditField.Enable = "on";
            app.IMUBackEditField.Enable = "on";
            app.LilysSpinner.Enable = "on";
            app.LilyDropDown.Enable = "on";
            app.COMDropDown.Enable = "on";
            app.UITable.Enable = "on";
        end
        
        function disableConnections(app)
            app.IMUArmEditField.Enable = "off";
            app.IMUBackEditField.Enable = "off";
            app.LilysSpinner.Enable = "off";
            app.LilyDropDown.Enable = "off";
            app.COMDropDown.Enable = "off";
            app.UITable.Enable = "off";
        end
    end


    methods (Access = private)

        % Button pushed function: ConnectSensorsButton
        function ConnectSensorsButtonPushed(app, event)
            if isempty(app.UITable.Data(:))
                app.Label.Text = 'Select # Lilys & PORTS';
                pause(1)
                app.Label.Text = '0';
                return
            end
            disableConnections(app);
            app.ConnectSensorsButton.Enable = "off";
            app.TestModeSwitch.Enable = "off";
            app.DisconnectSensorsButton.Enable = "on";
            if app.TestModeSwitch.Value == "Off"
                COMPortArm = app.IMUArmEditField.Value;
                COMPortBack = app.IMUBackEditField.Value;
                baudrate = 921600;          % rate at which information is transferred
                app.lpSensorArm = lpms();   % function lpms API sensor given by LPMS
                app.lpSensorBack = lpms();  % function lpms API sensor given by LPMS
                if ( ~app.lpSensorArm.connect(COMPortArm, baudrate) || ~app.lpSensorBack.connect(COMPortBack, baudrate) )
                    disp('IMU not connected')
                    enableConnections(app);
                    app.ConnectSensorsButton.Enable = "on";
                    app.TestModeSwitch.Enable = "on";
                    app.DisconnectSensorsButton.Enable = "off";
                    return
                end
                app.lpSensorArm.setStreamingMode();
                app.lpSensorBack.setStreamingMode();
                disp('IMU ready')
            end
            enableButtons(app);
            app.StatusLamp.Color = 'g';
        end

        % Button pushed function: DisconnectSensorsButton
        function DisconnectSensorsButtonPushed(app, event)
            enableConnections(app);
            app.ConnectSensorsButton.Enable = "on";
            app.TestModeSwitch.Enable = "on";
            app.DisconnectSensorsButton.Enable = "off";
            if app.TestModeSwitch.Value == "Off"
                if (app.lpSensorArm.disconnect() && app.lpSensorBack.disconnect())
                    disp('Sensor disconnected')
                end
            end
            disableButtons(app)
            app.StatusLamp.Color = 'r';
            if ~isempty(instrfind)
                fclose(instrfind);
                delete(instrfind);
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.stopButtonPressed = true;
            app.StartButton.Enable = "off";
            app.ConnectSensorsButton.Enable = "off";
            app.DisconnectSensorsButton.Enable = "off";
            
            if app.TestModeSwitch.Value == "Off"
                
                % arduino Connection
                for i=1:length(app.UITable.Data)
                    app.arduino(i,:) = serial(app.UITable.Data(i,2),'BaudRate',9600);
                    fopen(app.arduino(i));
                    flushinput(app.arduino(i));
                end
                
                % parameters
                nCount = 1;         % initial sample
                data = [];          % variable to save data
                nSens = 9;          % number of sensors including zero from each Lily
                Sample = [];        % sample stamp
                clockT = [];        % realTime
                dataIMUArm = [];    % IMU data Arm
                dataIMUBack = [];    % IMU data Back
                
                % format serial incoming data
                format = '';
                for nSen = 0: nSens
                    if nSen < nSens
                        format = [format , '%f,'];
                    else
                        format = [format , '%f'];
                    end
                end
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% HERE %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % reading
                disp('reading')
                while app.stopButtonPressed
                    d_IMU = app.lpSensorArm.getQueueSensorData();
                    if (~isempty(d_IMU))
                        dataIMUArm(:,nCount) = [d_IMU.timestamp, d_IMU.quat, d_IMU.acc];
                        data(:,nCount)= fscanf(app.arduino,format);
                        Sample(:,nCount) = nCount;
                        clockT(:,nCount) = clock;
                        app.Label.Text = int2str(nCount);
                        nCount = nCount + 1;
                    end
                    if nCount > 50
                        app.StatusLamp.Color = 'b';
                    end
                    drawnow()
                end
                app.StatusLamp.Color = 'g';
                
                %%% CREATE TABLE AND THEN THE FILE: https://es.mathworks.com/help/matlab/ref/writetable.html
                
                %% Storing data seccion
                % formatting
                %format1 = ''; format2 = '';
                format1 = '%12s%12s%12s%12s%12s%12s%12s%12s%12s%12s%12s%12s'; 
                format2 = '%12.0f%12d%12d%12.3f%12.2f%12.6f%12.6f%12.6f%12.6f%12.5f%12.5f%12.5f';
                
                str = strings([1,(nSen + 12)]);
                str(1) = 'Sample'; str(2) = 'Hour'; str(3) = 'Minute'; str(4) = 'Sec';
                str(5) = 'Stamp'; str(6) = 'Quat1'; str(7) = 'Quat2'; str(8) = 'Quat3'; str(9) = 'Quat4';
                str(10) = 'AccX'; str(11) = 'AccY'; str(12) = 'AccZ';
                for nSen = 0: nSens
                    if nSen < nSens
                        format1 = [format1 , '%12s'];
                        format2 = [format2 , '%12.2f'];
                        str(nSen+13) = ['A', int2str(nSen)];
                    else
                        format1 = [format1 , '%12s\r\n'];
                        format2 = [format2 , '%12.2f\r\n'];
                        str(nSen+13) = ['A', int2str(nSen)];
                    end
                end
                
                % storing Data
                
                clockT2 = clockT(4:6,:);
                Hour = clockT2(1,:);
                Min = clockT2(2,:);
                Sec = clockT2(3,:);
                
                folderName = 'testsData';
                [status, msg, msgID] = mkdir(folderName);
                dir = [pwd, '\',folderName];
                editFieldText = app.InitialsEditField.Value;
                gesture = app.GestureKnob.Value;
                S = char(datetime('now','Format','yyyy-MM-dd''T''HHmmssSSS'));
                fileName = [editFieldText, '_', gesture, '_', S,'.txt'];
                C = cat(1, Sample, Hour, Min, Sec, dataIMUArm, data);
                fileID = fopen(fullfile(dir,fileName),'wt');
                fprintf(fileID, format1, str); % col names
                fprintf(fileID, format2, C);
                fclose(fileID);
                disp('Done')
            end
            pause(1)
            app.StartButton.Enable = "on";
            app.DisconnectSensorsButton.Enable = "on";
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stopButtonPressed = false;
            if app.TestModeSwitch.Value == "Off"
                fclose(app.arduino);
            end
            app.Label.Text = "0";
            app.StartButton.Enable = "on";
        end

        % Value changed function: LilysSpinner
        function LilysSpinnerValueChanged(app, event)
            value = app.LilysSpinner.Value;
            list = "";
            for i=1:value
                newElement = ['Lily', int2str(i)];
                list(i,:) = {newElement};
                Name(i,:) = {newElement};
                COM(i,:) = {"COM"};
            end
            app.LilyDropDown.Items = list;
            T = [Name, COM];
            app.UITable.Data = cellstr(T);
            app.COMDropDown.Items = seriallist;
        end

        % Value changed function: COMDropDown
        function COMDropDownValueChanged(app, event)
            COM = {app.COMDropDown.Value};
            LilyNum = app.LilyDropDown.Value;
            num = app.LilysSpinner.Value;
            for i=1:num
                this = app.UITable.Data(i,1);
                if this{1} == LilyNum
                    app.UITable.Data(i,2) = COM;
                end
            end
            % disp(app.UITable.Data)
            % assignin('base','myVar',app.UITable.Data)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.IMUArmEditFieldLabel = uilabel(app.UIFigure);
            app.IMUArmEditFieldLabel.Text = 'IMU Arm';
            app.IMUArmEditField = uieditfield(app.UIFigure, 'text');
            app.IMUArmEditField.Value = 'COM4';
            app.COMARDEditFieldLabel = uilabel(app.UIFigure);
            app.COMARDEditFieldLabel.Text = 'COM ARD';
            app.COMARDEditField = uieditfield(app.UIFigure, 'text');
            app.COMARDEditField.Value = 'COM7';
            app.ConnectSensorsButton = uibutton(app.UIFigure, 'push');
            app.ConnectSensorsButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectSensorsButtonPushed, true);
            app.ConnectSensorsButton.Text = 'Connect Sensors';
            app.DisconnectSensorsButton = uibutton(app.UIFigure, 'push');
            app.DisconnectSensorsButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectSensorsButtonPushed, true);
            app.DisconnectSensorsButton.Enable = 'off';
            app.DisconnectSensorsButton.Text = 'Disconnect Sensors';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '0';
            app.StatusLampLabel = uilabel(app.UIFigure);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.UIFigure);
            app.GestureKnobLabel = uilabel(app.UIFigure);
            app.GestureKnobLabel.Text = 'Gesture';
            app.GestureKnob = uiknob(app.UIFigure, 'discrete');
            app.GestureKnob.Items = {'Green_Side', 'Green_Fron', 'Blue_F', 'Purple_F', 'Black_F', 'Purp_INV_F', 'Black_INV_F'};
            app.GestureKnob.Value = 'Green_Side';
            app.InitialsEditFieldLabel = uilabel(app.UIFigure);
            app.InitialsEditFieldLabel.Text = 'Initials';
            app.InitialsEditField = uieditfield(app.UIFigure, 'text');
            app.InitialsEditField.Value = 'Test_22_03';
            app.IMUBackEditFieldLabel = uilabel(app.UIFigure);
            app.IMUBackEditFieldLabel.Text = 'IMU Back';
            app.IMUBackEditField = uieditfield(app.UIFigure, 'text');
            app.IMUBackEditField.Value = 'COM5';
            app.LilyDropDownLabel = uilabel(app.UIFigure);
            app.LilyDropDownLabel.Text = 'Lily';
            app.LilyDropDown = uidropdown(app.UIFigure);
            app.LilyDropDown.Items = {'Lily1'};
            app.LilyDropDown.Value = 'Lily1';
            app.COMDropDownLabel = uilabel(app.UIFigure);
            app.COMDropDownLabel.Text = 'COM';
            app.COMDropDown = uidropdown(app.UIFigure);
            app.COMDropDown.Items = {'COM 1', 'Option 2', 'Option 3', 'Option 4'};
            app.COMDropDown.ValueChangedFcn = createCallbackFcn(app, @COMDropDownValueChanged, true);
            app.COMDropDown.Value = 'COM 1';
            app.LilysLabel = uilabel(app.UIFigure);
            app.LilysLabel.Text = '# Lilys';
            app.LilysSpinner = uispinner(app.UIFigure);
            app.LilysSpinner.Limits = [1 5];
            app.LilysSpinner.RoundFractionalValues = 'on';
            app.LilysSpinner.ValueDisplayFormat = '%.0f';
            app.LilysSpinner.ValueChangedFcn = createCallbackFcn(app, @LilysSpinnerValueChanged, true);
            app.LilysSpinner.Value = 1;
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Lilys'; 'COM'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true];
            app.TestModeSwitchLabel = uilabel(app.UIFigure);
            app.TestModeSwitchLabel.Text = 'Test Mode';
            app.TestModeSwitch = uiswitch(app.UIFigure, 'slider');
        end
    end

    methods (Access = public)

        % Construct app
        function app = get_data_Test

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end